classdef Pipeline_Builder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImportButton                  matlab.ui.control.Button
        ChooseCapListBox              matlab.ui.control.ListBox
        ChooseCapListBoxLabel         matlab.ui.control.Label
        AddEEGPositionGroup           matlab.ui.container.ButtonGroup
        ImportFileButton              matlab.ui.control.RadioButton
        UseDefaultPatternButton       matlab.ui.control.RadioButton
        SavePipelineButton            matlab.ui.control.Button
        BandPassFrequencesPanel       matlab.ui.container.Panel
        HighCutOffHzEditField         matlab.ui.control.NumericEditField
        HighCutOffHzEditFieldLabel    matlab.ui.control.Label
        LowCutOffHzEditField          matlab.ui.control.NumericEditField
        LowCutOffHzEditFieldLabel     matlab.ui.control.Label
        NotchGroup                    matlab.ui.container.ButtonGroup
        Frequence                     matlab.ui.control.TextArea
        SpecificFrequenceButton       matlab.ui.control.RadioButton
        EuropeButton                  matlab.ui.control.RadioButton
        NorthAmericaButton            matlab.ui.control.RadioButton
        ICACheckBox                   matlab.ui.control.CheckBox
        AverageReferenceCheckBox      matlab.ui.control.CheckBox
        PowerSpectrumDensityCheckBox  matlab.ui.control.CheckBox
        BandPassFilterCheckBox        matlab.ui.control.CheckBox
        NotchFilterCheckBox           matlab.ui.control.CheckBox
        ProjectElectrodeonScalpCheckBox  matlab.ui.control.CheckBox
        RefineRegistrationCheckBox    matlab.ui.control.CheckBox
        AddEEGPositionCheckBox        matlab.ui.control.CheckBox
        BrowseButton                  matlab.ui.control.Button
        SaveinFolderEditField         matlab.ui.control.EditField
        SaveinFolderEditFieldLabel    matlab.ui.control.Label
        PipelineNameEditField         matlab.ui.control.EditField
        PipelineNameEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Description
        
        Path = '/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/';
        
        Pipeline = struct();
        
        AddEEGPosition = struct();
        RefineRegistration = struct();
        ProjectElectrodeOnScalp = struct();
        NotchFilter = struct();
        BandPassFilter = struct();
        PowerSpectrumDensity = struct();
        AverageReference = struct();
        ICA = struct();
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            path = uigetdir('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/');
            app.SaveinFolderEditField.Value = path;
            
            fieldPosition = app.SaveinFolderEditField.Position;
            assignin("base", "long", length(path))
            app.SaveinFolderEditField.Position = [fieldPosition(1), fieldPosition(2), length(path) + 300, fieldPosition(4)];
        end

        % Value changed function: AddEEGPositionCheckBox
        function AddEEGPositionCheckBoxValueChanged(app, event)

            app.AddEEGPosition.ToRun = app.AddEEGPositionCheckBox.Value;
            
            if app.AddEEGPosition.ToRun
                
                app.AddEEGPositionGroup.Visible = true;
                AddEEGPositionGroupSelectionChanged(app, event)
                
            elseif not(app.AddEEGPosition.ToRun)
                
                app.AddEEGPositionGroup.Visible = false;
                app.ChooseCapListBox.Visible = false;
                
            end
            
        end

        % Value changed function: RefineRegistrationCheckBox
        function RefineRegistrationCheckBoxValueChanged(app, event)
            
            app.RefineRegistration.ToRun = app.RefineRegistrationCheckBox.Value;
            
        end

        % Value changed function: ProjectElectrodeonScalpCheckBox
        function ProjectElectrodeonScalpCheckBoxValueChanged(app, event)
            
            app.ProjectElectrodeOnScalp.ToRun = app.ProjectElectrodeonScalpCheckBox.Value;
            
        end

        % Value changed function: NotchFilterCheckBox
        function NotchFilterCheckBoxValueChanged(app, event)
            
            app.NotchFilter.ToRun = app.NotchFilterCheckBox.Value;
            
            if app.NotchFilter.ToRun
                
                app.NotchGroup.Visible = true;
                NotchGroupSelectionChanged(app, event);
               
            elseif not(app.NotchFilter.ToRun)
                
                app.NotchGroup.Visible = false;
                
            end
            
        end

        % Value changed function: BandPassFilterCheckBox
        function BandPassFilterCheckBoxValueChanged(app, event)
            
            app.BandPassFilter.ToRun = app.BandPassFilterCheckBox.Value;
            
            if app.BandPassFilter.ToRun
                
                app.BandPassFrequencesPanel.Visible = true;
                app.BandPassFilter.Parameters = zeros(1, 2);
               
            elseif not(app.BandPassFilter.ToRun)
                
                app.BandPassFrequencesPanel.Visible = false;
                app.BandPassFilter.Parameters = rmfield(app.BandPassFilter.Parameters, "Parameters");
                
            end
            
        end

        % Value changed function: PowerSpectrumDensityCheckBox
        function PowerSpectrumDensityCheckBoxValueChanged(app, event)
            
            app.PowerSpectrumDensity.ToRun = app.PowerSpectrumDensityCheckBox.Value;
            
        end

        % Value changed function: AverageReferenceCheckBox
        function AverageReferenceCheckBoxValueChanged(app, event)
            
            app.AverageReference.ToRun = app.AverageReferenceCheckBox.Value;
            
        end

        % Value changed function: ICACheckBox
        function ICACheckBoxValueChanged(app, event)
            
            app.ICA.ToRun = app.ICACheckBox.Value;
            
        end

        % Selection changed function: NotchGroup
        function NotchGroupSelectionChanged(app, event)
            selectedButton = app.NotchGroup.SelectedObject;

            switch selectedButton
                
                case app.EuropeButton
                    
                    parameters = [50, 100, 150, 200];
                    app.Frequence.Visible = false;
                    
                case app.NorthAmericaButton
                    
                    parameters = [60, 120, 180, 240];
                    app.Frequence.Visible = false;
                    
                case app.SpecificFrequenceButton
                    
                    app.Frequence.Visible = true;
                    
                    frequences = app.Frequence.Value;
                    
                    % Assuming frequences are separated by commas.
                    frequences = split(frequences, ',');
                    nbFrequence = length(frequences);
                     
                    parameters = zeros(1, nbFrequence);
                    for i = 1:nbFrequence
                        parameters(i) = string(frequences{i});
                    end
            end

            app.NotchFilter.Parameters =  parameters;
        end

        % Value changed function: LowCutOffHzEditField
        function LowCutOffHzEditFieldValueChanged(app, event)
            
            value = app.LowCutOffHzEditField.Value;
            
            app.BandPassFilter.Parameters(1) = value;
            
        end

        % Value changed function: HighCutOffHzEditField
        function HighCutOffHzEditFieldValueChanged(app, event)
            
            value = app.HighCutOffHzEditField.Value;
            
            app.BandPassFilter.Parameters(2) = value;
            
        end

        % Selection changed function: AddEEGPositionGroup
        function AddEEGPositionGroupSelectionChanged(app, event)
            selectedButton = app.AddEEGPositionGroup.SelectedObject;
            
            switch selectedButton
                
                case app.UseDefaultPatternButton
                    app.ChooseCapListBox.Visible = true;
                    ChooseCapListBoxValueChanged(app, event);
                    
                case app.ImportFileButton
                    app.ChooseCapListBox.Visible = false;
            end
        end

        % Value changed function: ChooseCapListBox
        function ChooseCapListBoxValueChanged(app, event)


            app.AddEEGPosition.Cap = app.ChooseCapListBox.Value;
            
            switch app.AddEEGPosition.Cap
                case "Colin27: BrainProducts EasyCap 128"
                    capNumber = 22;
                otherwise
                    capNumber = 0;
            end

            app.AddEEGPosition.Cap = app.AddEEGPosition.Cap;
            app.AddEEGPosition.CapNumber = capNumber;
            
            
        end

        % Value changed function: Frequence
        function FrequenceValueChanged(app, event)
            value = app.Frequence.Value;
            
            position = app.Frequence.Position;
            
            app.Frequence.Position = [position(1) position(2) length(value{1}) + 100 position(4)];
            
        end

        % Value changed function: PipelineNameEditField
        function PipelineNameEditFieldValueChanged(app, event)
            value = app.PipelineNameEditField.Value;
            
            assignin("base", "value", value);
            
            position = app.PipelineNameEditField.Position;
            
            app.PipelineNameEditField.Position = [position(1) position(2) length(value) + 200 position(4)];
        end

        % Button pushed function: SavePipelineButton
        function SavePipelineButtonPushed(app, event)

            app.Pipeline.AddEEGPosition = app.AddEEGPosition;
            app.Pipeline.RefineRegistration = app.RefineRegistration;
            app.Pipeline.ProjectElectrodeOnScalp = app.ProjectElectrodeOnScalp;
            app.Pipeline.NotchFilter = app.NotchFilter;
            app.Pipeline.BandPassFilter = app.BandPassFilter;
            app.Pipeline.PowerSpectrumDensity = app.PowerSpectrumDensity;
            app.Pipeline.AverageReference = app.AverageReference;
            app.Pipeline.ICA = app.ICA;
            
            Pipeline = app.Pipeline;

            cd(app.SaveinFolderEditField.Value);
            save(strcat(app.PipelineNameEditField.Value, '.mat'), "Pipeline");
            cd('/mnt/3b5a15cf-20ff-4840-8d84-ddbd428344e9/ALAB1/corentin');
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            
            [fileName, folderPath] = uigetfile('*.mat', 'Select MAT file', app.Path);
            
            app.PipelineNameEditField.Value = fileName;
            app.SaveinFolderEditField.Value = folderPath;

            app.Pipeline = load(strcat(folderPath, fileName));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PipelineNameEditFieldLabel = uilabel(app.UIFigure);
            app.PipelineNameEditFieldLabel.Text = 'Pipeline Name';
            app.PipelineNameEditField = uieditfield(app.UIFigure, 'text');
            app.PipelineNameEditField.ValueChangedFcn = createCallbackFcn(app, @PipelineNameEditFieldValueChanged, true);
            app.SaveinFolderEditFieldLabel = uilabel(app.UIFigure);
            app.SaveinFolderEditFieldLabel.Text = 'Save in Folder';
            app.SaveinFolderEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.AddEEGPositionCheckBox = uicheckbox(app.UIFigure);
            app.AddEEGPositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddEEGPositionCheckBoxValueChanged, true);
            app.AddEEGPositionCheckBox.Text = 'Add EEG Position';
            app.RefineRegistrationCheckBox = uicheckbox(app.UIFigure);
            app.RefineRegistrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefineRegistrationCheckBoxValueChanged, true);
            app.RefineRegistrationCheckBox.Text = 'Refine Registration';
            app.ProjectElectrodeonScalpCheckBox = uicheckbox(app.UIFigure);
            app.ProjectElectrodeonScalpCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProjectElectrodeonScalpCheckBoxValueChanged, true);
            app.ProjectElectrodeonScalpCheckBox.Text = 'Project Electrode on Scalp';
            app.NotchFilterCheckBox = uicheckbox(app.UIFigure);
            app.NotchFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchFilterCheckBoxValueChanged, true);
            app.NotchFilterCheckBox.Text = 'Notch Filter';
            app.BandPassFilterCheckBox = uicheckbox(app.UIFigure);
            app.BandPassFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @BandPassFilterCheckBoxValueChanged, true);
            app.BandPassFilterCheckBox.Text = 'Band-Pass Filter';
            app.PowerSpectrumDensityCheckBox = uicheckbox(app.UIFigure);
            app.PowerSpectrumDensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @PowerSpectrumDensityCheckBoxValueChanged, true);
            app.PowerSpectrumDensityCheckBox.Text = 'Power Spectrum Density';
            app.AverageReferenceCheckBox = uicheckbox(app.UIFigure);
            app.AverageReferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageReferenceCheckBoxValueChanged, true);
            app.AverageReferenceCheckBox.Text = 'Average Reference';
            app.ICACheckBox = uicheckbox(app.UIFigure);
            app.ICACheckBox.ValueChangedFcn = createCallbackFcn(app, @ICACheckBoxValueChanged, true);
            app.ICACheckBox.Text = 'ICA';
            app.NotchGroup = uibuttongroup(app.UIFigure);
            app.NotchGroup.SelectionChangedFcn = createCallbackFcn(app, @NotchGroupSelectionChanged, true);
            app.NotchGroup.Title = 'Button Group';
            app.NotchGroup.Visible = 'off';
            app.NorthAmericaButton = uiradiobutton(app.NotchGroup);
            app.NorthAmericaButton.Text = 'North America (60Hz, 120Hz, 180Hz, 240Hz)';
            app.NorthAmericaButton.Value = true;
            app.EuropeButton = uiradiobutton(app.NotchGroup);
            app.EuropeButton.Text = 'Europe (50Hz, 100Hz, 150Hz, 200Hz)';
            app.SpecificFrequenceButton = uiradiobutton(app.NotchGroup);
            app.SpecificFrequenceButton.Text = 'Specific Frequence:';
            app.Frequence = uitextarea(app.NotchGroup);
            app.Frequence.ValueChangedFcn = createCallbackFcn(app, @FrequenceValueChanged, true);
            app.Frequence.Visible = 'off';
            app.BandPassFrequencesPanel = uipanel(app.UIFigure);
            app.BandPassFrequencesPanel.Title = 'Band Pass Frequences';
            app.BandPassFrequencesPanel.Visible = 'off';
            app.LowCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.LowCutOffHzEditFieldLabel.Text = 'Low Cut Off (Hz)';
            app.LowCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.LowCutOffHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowCutOffHzEditFieldValueChanged, true);
            app.HighCutOffHzEditFieldLabel = uilabel(app.BandPassFrequencesPanel);
            app.HighCutOffHzEditFieldLabel.Text = 'High Cut Off (Hz)';
            app.HighCutOffHzEditField = uieditfield(app.BandPassFrequencesPanel, 'numeric');
            app.HighCutOffHzEditField.ValueChangedFcn = createCallbackFcn(app, @HighCutOffHzEditFieldValueChanged, true);
            app.SavePipelineButton = uibutton(app.UIFigure, 'push');
            app.SavePipelineButton.ButtonPushedFcn = createCallbackFcn(app, @SavePipelineButtonPushed, true);
            app.SavePipelineButton.Text = 'Save Pipeline';
            app.AddEEGPositionGroup = uibuttongroup(app.UIFigure);
            app.AddEEGPositionGroup.SelectionChangedFcn = createCallbackFcn(app, @AddEEGPositionGroupSelectionChanged, true);
            app.AddEEGPositionGroup.Title = 'Button Group2';
            app.AddEEGPositionGroup.Visible = 'off';
            app.UseDefaultPatternButton = uiradiobutton(app.AddEEGPositionGroup);
            app.UseDefaultPatternButton.Text = 'Use Default Pattern';
            app.UseDefaultPatternButton.Value = true;
            app.ImportFileButton = uiradiobutton(app.AddEEGPositionGroup);
            app.ImportFileButton.Text = 'Import File';
            app.ChooseCapListBoxLabel = uilabel(app.UIFigure);
            app.ChooseCapListBoxLabel.Visible = 'off';
            app.ChooseCapListBoxLabel.Text = 'Choose Cap:';
            app.ChooseCapListBox = uilistbox(app.UIFigure);
            app.ChooseCapListBox.Items = {'Colin27: BrainProducts EasyCap 128'};
            app.ChooseCapListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseCapListBoxValueChanged, true);
            app.ChooseCapListBox.Visible = 'off';
            app.ChooseCapListBox.Value = 'Colin27: BrainProducts EasyCap 128';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pipeline_Builder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end